classdef appAutopilot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIAerobiUIFigure              matlab.ui.Figure
        RealTimeExternalcontrolPanel  matlab.ui.container.Panel
        ConnectButton                 matlab.ui.control.Button
        BuildButton                   matlab.ui.control.Button
        LaunchButton                  matlab.ui.control.Button
        TotalStationButton            matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        ShutdownRaspiButton           matlab.ui.control.Button
        PX4Button                     matlab.ui.control.Button
        StatusPanel                   matlab.ui.container.Panel
        ModeLabel                     matlab.ui.control.Label
        PlotlogsPanel                 matlab.ui.container.Panel
        SelectsignalDropDownLabel     matlab.ui.control.Label
        SelectsignalDropDown          matlab.ui.control.DropDown
        GeneratePlotButton            matlab.ui.control.Button
        SelectLogLabel                matlab.ui.control.Label
        SelectLogDropDown             matlab.ui.control.DropDown
        UploadButton                  matlab.ui.control.Button
        ParametersPanel               matlab.ui.container.Panel
        ControllersButton             matlab.ui.control.Button
        FiltersButton                 matlab.ui.control.Button
        ControlmodesButton            matlab.ui.control.Button
        XButton                       matlab.ui.control.Button
        ConnectionLabel               matlab.ui.control.Label
        Button                        matlab.ui.control.Button
    end

%TODO:
%
    properties (Access = private)
        %pathWorkspace = '/home/ae-grvc/Documentos/Workspace/AutoPilot/';
        pathWorkspace
        pathProject %Path project
        pathLogs %Path for the Logs.
        pathDiagView %Path for the errors diary
        pathSlprj %Path of slprj folder
        
        nameModelSimulink 
        nameProjectFolder        
        nameLogs
        nameRaspi
        
        timerCheckFlightMode %Timer to check the Flight mode selected.
        timerCheckBuildState %Timer to control the build steps.
        timerCheckBoardIsConnected %Timer to check if the board and the PC are connected to the same network.
        timerPeriod = 1; %Timers period
        timerIsConnectedPeriod = 5; %Timers period
        
        CommandlaunchPutty
        commandSendMainRaspi %Send to the Raspi the modified ert_main.cpp.
        commandBuildinRaspi %Build the project with the modified ert_main.cpp and copy the new executable to ~/remote.
        commandLaunchExecutables %Launch the executables in the raspi.
        commandKillExecutables %Kill process running in the raspi.
        commandShutdownRaspi %Shutdown raspi
        commandLaunchTotalStation %Launch Total Station Driver
        %commandUpdateErtMain %Generate new ert_main.cpp           
        commandLaunchPX4 %Launch PX4  
        commandLaunchBridge
               
        numRotors %Select quad, hexa o octo
        
        ipAddress
        
        stateButtons = {'off','on','on','on','off','off','on','on','on','on','on','on','on','on','on','on'}; %Variable used for update the state of the buttons            
        stateConnection = 0; %Where 0 = stopped, 1 = launched/disconnected and 2 = connected 
        stateBuild = 0; %State of the build process
        stateRaspi = 0; %State Raspi {On = 1|Off = 0}
        numDisconnectedChecked = 0; %If this num is more than 5, the raspi is disconnected and the timer should be stopped 
        
        numFlights = 0; %Count the number of "flights"
        
        figStopSimulation %Figure stop simulation question
       
        flightMode %Read flight mode from an output port of the simulink mode
        
        plotFigure = 0;
        logSeleted
        logData
  
    end

    methods (Access = private)
        
        %%%%%%%%%%%State of Buttons function%%%%%%%%%%%%%%%%%%%%%%
        function updateButtonsState(app)
            %%%RealTime external control panel%%%
            app.ConnectButton.Enable = app.stateButtons{1};
            app.BuildButton.Enable = app.stateButtons{2}; 
            app.LaunchButton.Enable = app.stateButtons{3}; 
            app.TotalStationButton.Enable = app.stateButtons{4};
            app.StartButton.Enable = app.stateButtons{5};
            app.StopButton.Enable = app.stateButtons{6};
            app.ShutdownRaspiButton.Enable = app.stateButtons{7};   
            app.PX4Button.Enable = app.stateButtons{8};
            %%%Plot logs panel%%%
            app.SelectsignalDropDown.Enable = app.stateButtons{9};
            app.GeneratePlotButton.Enable = app.stateButtons{10};
            app.SelectLogDropDown.Enable = app.stateButtons{11};
            app.UploadButton.Enable = app.stateButtons{12};
            %%%Parameters panel%%%           
            app.ControllersButton.Enable = app.stateButtons{13};
            app.FiltersButton.Enable = app.stateButtons{14};
            app.ControlmodesButton.Enable = app.stateButtons{15};
            %%%Kill Button
            app.XButton.Enable = app.stateButtons{16};
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function stopQuestionFig_fcn(app)
            app.figStopSimulation = uifigure('Position',[100 100 350 275],...
                'CloseRequestFcn',@(app, event) stopSimulation_fcn(app.fig));
        end
        
        %%%Stop the simulation and kill process
        function stopSimulation_fcn(app)
            selection = questdlg('Are you sure of kill all process?BE CAREFUL!!Check if the rotors battery is disconnected',...
                                'Confirmation',...
                                   'Yes','No','Yes');
            switch selection
                    case 'Yes'
                        delete(app.figStopSimulation);                     
                        status = get_param(bdroot,'simulationstatus');
                        if strcmp(status,'external')
                            set_param(bdroot,'simulationcommand','stop');
                        end                     
                        app.ModeLabel.Text = 'Waiting...';
                        if (isvalid(app.timerCheckFlightMode) && strcmp(app.timerCheckFlightMode.Running,'on') == 1)
                            stop(app.timerCheckFlightMode);
                            killPX4();%getLogs(); % Update and charge the logs and kill PX4                
                            updateLogFiles(app);
                            app.numFlights = app.numFlights + 1;
                            start(app.timerCheckBoardIsConnected);
                        end
                        app.ConnectButton.Text = 'Connect';
                        app.stateButtons ={'off','on','on','on','off','off','on','on','on','on','on','on','off','off','on','on'};
                        updateButtonsState(app);
                        app.stateConnection = 0;
                        set_param([app.nameModelSimulink,'/Status/isStarted'],'Value',num2str(0));
                        
                    case 'No'
                        return
            end
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%Check board connection%%%%%%%%%%% 
        function timerCheckBoardIsConnected_fcn(app, ~, ~, ~)
            if (strcmp(app.timerCheckBoardIsConnected.Running,'on') == 1)
                [ip,check] = isConnected(app.ipAddress);
                
                if check
                    %disp('check!');
                    app.numDisconnectedChecked = 0;
                    if app.stateRaspi == 0
                        app.stateRaspi = 1;                        
                    end
                    if ~isempty(ip)
                        %disp('ip!');
                        app.ipAddress = ip{1};                        
                    end
                    if app.stateRaspi == -1
                        app.ConnectionLabel.Text = 'Shutting Down...';
                        app.ConnectionLabel.FontColor = 'r';
                        if ((strcmp(app.timerCheckFlightMode.Running,'off') == 1) || (strcmp(app.timerCheckBuildState.Running,'off') == 1))
                            app.stateButtons ={'off','off','off','off','off','off','off','off','on','on','on','on','off','off','on','off'};
                            updateButtonsState(app);
                        end
                    else
                        app.ConnectionLabel.Text = ['Connected(',app.ipAddress,')'];
                        app.ConnectionLabel.FontColor = 'g';
                        if ((strcmp(app.timerCheckFlightMode.Running,'off') == 1) || (strcmp(app.timerCheckBuildState.Running,'off') == 1))
                            app.stateButtons ={'off','on','on','on','off','off','on','on','on','on','on','on','off','off','on','on'};
                            updateButtonsState(app);
                        end
                        
                        stop(app.timerCheckBoardIsConnected); %Stop build state timer                            
                    end
                else
                    %disp('~check!');

                    app.ConnectionLabel.Text = 'Disconnected';
                    app.ConnectionLabel.FontColor = 'r';
                    app.numDisconnectedChecked = app.numDisconnectedChecked + 1;
                    if ((strcmp(app.timerCheckFlightMode.Running,'off') == 1) || (strcmp(app.timerCheckBuildState.Running,'off') == 1))
                        app.stateButtons ={'off','off','off','off','off','off','off','off','on','on','on','on','off','off','on','off'};
                        updateButtonsState(app);
                    end
                    if app.numDisconnectedChecked > 3
                       stop(app.timerCheckBoardIsConnected); %Stop build state timer
                       app.numDisconnectedChecked = 0;
                    end
                    if app.stateRaspi == -1
                        app.stateRaspi = 0;
                        app.ConnectionLabel.Text = 'Shutted Down';
                        app.ConnectionLabel.FontColor = 'g';
                        system('sudo pkill -9 putty');
                        stop(app.timerCheckBoardIsConnected); %Stop build state timer
                    end
                        
                end
            else
                disp('Timer check connection is not running!');
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%Flight mode state machine%%%%%%%%
        function timerCheckFlightMode_fcn(app, ~, ~, ~)
            if (strcmp(app.timerCheckFlightMode.Running,'on') == 1)
                    app.RealTimeExternalcontrolPanel.HandleVisibility = 'on';
                    switch app.stateConnection
                        case 0 %No launched 
                            app.stateButtons ={'off','on','on','on','off','off','on','on','on','on','on','on','off','off','on','on'};
                            updateButtonsState(app); 
                        case 1 %Launched and external mode disconnected
                            app.stateButtons ={'on','off','off','on','off','off','on','on','on','on','on','on','off','off','on','on'};
                            updateButtonsState(app); 
                        case 2 %External mode connected and paused
                            app.stateButtons ={'on','off','off','on','on','off','on','on','on','on','on','on','off','off','on','on'};
                            updateButtonsState(app);
                        case 3 %External mode connected and running
                            app.stateButtons ={'on','off','off','on','off','on','on','on','on','on','on','on','off','off','on','on'};
                            updateButtonsState(app);
                            set(0,'ShowHiddenHandles','on');
                            rto = get_param(strcat(app.nameModelSimulink,'/StateMode'),'RuntimeObject');
                            str = rto.InputPort(1).Data;
                            app.flightMode = num2str(str(1)) + num2str(str(2)) + num2str(str(3));
                            if (app.flightMode == 144)            
                                app.ModeLabel.Text = 'Motor Off';
                                app.stateButtons{15} ='on';
                                updateButtonsState(app);
                            elseif(app.flightMode == 145)
                                app.ModeLabel.Text = 'Attitude (Full-Rate mode)';
                                app.stateButtons{15} ='off';
                                updateButtonsState(app);
                            elseif(app.flightMode == 146)
                                app.ModeLabel.Text = 'Attitude (Yaw rate mode)';
                                app.stateButtons{15} ='off';
                                updateButtonsState(app);
                            elseif(app.flightMode == 147)
                                app.ModeLabel.Text = 'Attitude (Full-Stabilized  mode)';
                                app.stateButtons{15} ='off';
                                updateButtonsState(app);
                            elseif(app.flightMode == 148)
                                app.ModeLabel.Text = 'Altitude (Velocity mode)';
                                app.stateButtons{15} ='off';
                                updateButtonsState(app);
                            elseif(app.flightMode == 149)
                                app.ModeLabel.Text = 'Altitude (Position mode)';
                                app.stateButtons{15} ='off';
                                updateButtonsState(app);
                            elseif(app.flightMode == 150)
                                app.ModeLabel.Text = 'Position (Velocity mode)';
                                app.stateButtons{15} ='off';
                                updateButtonsState(app);
                           elseif(app.flightMode == 151)
                                app.ModeLabel.Text = 'Position (Position mode)';
                                app.stateButtons{15} ='off';
                                updateButtonsState(app);
                           elseif(app.flightMode == 152)
                                app.ModeLabel.Text = 'Position (Autonomous mode)';
                                app.stateButtons{15} ='off';
                                updateButtonsState(app);
                            else
                                app.ModeLabel.Text = 'Waiting...'; 
                            end
                        otherwise
                            disp('Something was wrong!');
                    end
            else
                disp('Timer check flight mode is not running!');
            end        
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 
        %%%
        function updated = updateLogFiles(app)
            Files = dir(strcat(app.pathLogs,app.nameLogs));
            if ~isempty(Files)
                app.SelectLogDropDown.Items = sortLogFiles(app);               
            else
                app.SelectLogDropDown.Items = {'No logs'};              
            end
            sz = size(app.SelectLogDropDown.Items);
            updated = sz(1);
        end
        
        function deleted = deleteBuildLogFile(app)
            %Delete the log generates by the matlab compilation process. 
            waitfor(updateLogFiles(app)); 
            sortfiles = sortLogFiles(app);
            sz = size(sortfiles);
            nameFileDeleted = sortfiles(sz(1));
            disp('Erasing compilation log file...')
            nameFileDeleted = strcat(app.pathLogs,nameFileDeleted);
            delete(nameFileDeleted{1});
            deleted = nameFileDeleted;
            disp('Erased!')
        end
        
        function sortfiles = sortLogFiles(app)
            Files = dir(strcat(app.pathLogs,app.nameLogs));
            if ~isempty(Files)
                Afields = fieldnames(Files);
                Acell = struct2cell(Files);
                sz = size(Acell);
                % Convert to a matrix
                Acell = reshape(Acell, sz(1), []);      % Px(MxN)
                % Make each field a column
                Acell = Acell';                         % (MxN)xP
                % Sort by first field "date"
                Acell = sortrows(Acell, 3);
                sortfiles = Acell(:,1);
            else
                sortfiles = {'No logs'};
            end
        end
        %%%%%%%%%%%%%%%%%Check simulink compilation errors%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function errors_founded = checkSimulinkCompilationErrors(app)
        %Check if there are any simulink compilation errors: 
            fid  = fopen(app.pathDiagView,'r'); %%Open the errors diary
            text = textscan(fid,'%s','Delimiter','','endofline','');
            text = text{1}{1};
            errors_founded = strfind(text,'Error:');
            fclose(fid);            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%%%%%%Build proccess state machine%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function timerCheckBuildState_fcn(app, ~, ~, ~)
            if (strcmp(app.timerCheckBuildState.Running,'on') == 1) %%%Check if the timer is running
                app.RealTimeExternalcontrolPanel.HandleVisibility = 'on';
                
                switch app.stateBuild
                    case 0 %The build has not still started
                        if strcmp(get_param(bdroot,'simulationstatus'),'stopped') == 1
                           updateLogFiles(app);  %Update Log files
                        end
                    case 1 %The simulink compilation has started
                        if strcmp(get_param(bdroot,'simulationstatus'),'stopped') == 1
                            %Check if there are any simulink compilation errors:
                            errors_founded = checkSimulinkCompilationErrors(app);
                            if ~isempty(errors_founded) %Simulink compilation failed
                                app.ModeLabel.Text = 'Simulink compilation failed!';
                                app.stateBuild = 0; %Cancel the compilation process
                                app.stateButtons ={'off','on','on','on','off','off','on','on','on','on','on','on','off','off','on','on'};
                                updateButtonsState(app);
                            else
                                app.ModeLabel.Text = 'Simulink compilation Completed!';                                             
                                app.stateBuild = 2;
                            end
                            sldiagviewer.diary('off');                      
                            delete(app.pathDiagView);
                        else
                            if (strcmp(get_param(bdroot,'simulationstatus'),'external') ~= 0) && (get_param(bdroot,'SimulationTime') > 1)                           
                                set_param(bdroot, 'SimulationCommand', 'stop');
                                waitfor(updateLogFiles(app));                                               
                            end
                        end                      
                    case 2 %The simulink compilation was completed and the modified ert_main.cpp must be send too the raspberry pi
                        if strcmp(get_param(bdroot,'simulationstatus'),'stopped') == 1
                            %run(app.commandUpdateErtMain); %Update ert_main.cpp
                            set_ert_main(app.pathProject,app.nameModelSimulink,app.numRotors);
                            system(app.commandSendMainRaspi); %Send ert_main.cpp
                            waitfor(updateLogFiles(app)); %Update the log selected
                            Files = dir(strcat(app.pathLogs,app.nameLogs));   
                            if ~isempty(Files)
                                waitfor(updateLogFiles(app)); %the log compilation file must be deleted 
                                %waitfor(deleteBuildLogFile(app)); %Delete the simulation log file
                                app.stateBuild = 3;
                            else
                                app.stateBuild = 3;
                            end
                        end
                    case 3 %Rebuild the modified project in the raspberry pi
                        if strcmp(get_param(bdroot,'simulationstatus'),'stopped') == 1
                            app.ModeLabel.Text = 'Building in Raspberry Pi ...';
                            system(app.commandBuildinRaspi);
                            app.ModeLabel.Text = 'Compilation completed!';
                            app.stateButtons ={'off','on','on','on','off','off','on','on','on','on','on','on','off','off','on','on'};
                            updateButtonsState(app);
                            if (isvalid(app.timerCheckBuildState) && strcmp(app.timerCheckBuildState.Running,'on') == 1)
                                stop(app.timerCheckBuildState);
                                start(app.timerCheckBoardIsConnected);
                            end
                            app.stateBuild = 0;             
                            waitfor(updateLogFiles(app));
                        end
                    otherwise
                end
            else
                disp('No running.');
            end        
                   
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    end


    methods (Access = private)

        % Code that executes after component creation
        function start_fcn(app)
            clc;
            %%%%%%%%%%%%Generate the paths%%%%%%%%%%%%
            [app.pathWorkspace,app.nameModelSimulink,app.nameProjectFolder,app.nameLogs,app.numRotors,app.nameRaspi] = generatePathandNames();
            app.pathProject = [app.pathWorkspace,app.nameProjectFolder];
            app.pathLogs = [app.pathProject,'Logs/'];
            app.pathDiagView = [app.pathProject,'diary.txt'];
            app.pathSlprj = [app.pathProject,'slprj/'];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%Generate the commands needed%%%%%%%%% 
            app.commandSendMainRaspi = ['psftp ',app.nameRaspi,' -l pi -pw raspberry -b ',app.pathProject,'Executables/transfer_ert_main.txt'];
            app.CommandlaunchPutty = ['putty -ssh ',app.nameRaspi,' -l pi -pw raspberry -m '];
            app.commandBuildinRaspi = [app.CommandlaunchPutty,app.pathProject,'Executables/buildandCopy.txt'];
            app.commandLaunchExecutables = [app.CommandlaunchPutty,app.pathProject,'Executables/launch_drivers.txt'];
            app.commandKillExecutables = [app.CommandlaunchPutty,app.pathProject,'Executables/kill_drivers.txt'];
            app.commandShutdownRaspi = [app.CommandlaunchPutty,app.pathProject,'Executables/shutdown_raspi.txt'];      
            app.commandLaunchTotalStation = [app.pathProject,'Executables/laserSystemUSE 1 /dev/ttyUSB0 44000'];          
            app.commandLaunchPX4 = [app.CommandlaunchPutty,app.pathProject,'Executables/launch_px4.txt'];
            app.commandLaunchBridge = [app.CommandlaunchPutty,app.pathProject,'Executables/launch_bridge.txt'];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%Declaration of the Timers%%%%%%%%%%%
            app.timerCheckFlightMode = timer('ExecutionMode','fixedRate','Period',app.timerPeriod);
            app.timerCheckBuildState = timer('ExecutionMode','fixedRate','Period',app.timerPeriod);
            app.timerCheckBoardIsConnected = timer('ExecutionMode','fixedRate','Period',app.timerIsConnectedPeriod);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.stateButtons ={'off','off','off','off','off','off','off','off','on','on','on','on','off','off','on','off'};                      
            %app.stateButtons = {'off','on','on','on','off','off','on','on','on','on','on','on','on','on','on','on'}; %Variable used for update the state of the buttons
            updateButtonsState(app);
            app.ConnectionLabel.Text = 'Please push to connect...';
            
            open_system(strcat(app.nameModelSimulink,'.slx'));
            set_param(bdroot,'location',[242 0 1373 737]);
            waitfor(updateLogFiles(app));
            app.ModeLabel.Text = 'Waiting...';
            app.stateConnection = 0;
            app.numDisconnectedChecked = 0;
            
            app.timerCheckFlightMode.TimerFcn = {@app.timerCheckFlightMode_fcn, 'Start to check the flight mode'};
            app.timerCheckBuildState.TimerFcn = {@app.timerCheckBuildState_fcn, 'Start to check the build process'};
            app.timerCheckBoardIsConnected.TimerFcn = {@app.timerCheckBoardIsConnected_fcn, 'Start to check if the board is connected'};
            %start(app.timerCheckBoardIsConnected);
            app.numFlights = 0;
            app.stateRaspi = 0;
            set_param([app.nameModelSimulink,'/Status/isStarted'],'Value',num2str(0));
        end

        % Button pushed function: ConnectButton
        function connect_disconnect_fcn(app, event)
            status = get_param(bdroot,'simulationstatus');       
            if strcmp(status,'external')
                set_param(bdroot,'simulationcommand','disconnect');            
                app.ConnectButton.Text = 'Connect';
                app.stateConnection = 3;
                waitfor(updateLogFiles(app));
            else
                set_param(bdroot,'simulationcommand','connect');       
                app.ConnectButton.Text = 'Disconnect';
                app.stateConnection = 2;
                waitfor(updateLogFiles(app));
                
            end
        end

        % Button pushed function: StartButton
        function start_simulation(app, event)
            status = get_param(bdroot,'simulationstatus');
            if strcmp(status,'external')
                set_param(bdroot,'simulationcommand','start');
                app.stateConnection = 3;
                set_param([app.nameModelSimulink,'/Status/isStarted'],'Value',num2str(1));
            end     
        end

        % Button pushed function: StopButton
        function stop_simulation(app, event)
            stopSimulation_fcn(app);                   
        end

        % Close request function: UIAerobiUIFigure
        function close_fcn(app, event)
            models = find_system('SearchDepth',0);
            if isempty(models)~=1
                for i=1:length(models)
                    if strcmp(models(i),app.nameModelSimulink)==1 %Checks if the model simulink is openned.
                        save_system(strcat(app.nameModelSimulink,'.slx'));
                        close_system(strcat(app.nameModelSimulink,'.slx'));
                    end
                end
            end
            
            if (isvalid(app.timerCheckFlightMode) && strcmp(app.timerCheckFlightMode.Running,'on') == 1) 
                stop(app.timerCheckFlightMode); %Stop flight mode timer
            end
            if (isvalid(app.timerCheckBuildState) && strcmp(app.timerCheckBuildState.Running,'on') == 1)
                stop(app.timerCheckBuildState); %Stop build state timer
            end
            if (isvalid(app.timerCheckBoardIsConnected) && strcmp(app.timerCheckBoardIsConnected.Running,'on') == 1)
                stop(app.timerCheckBoardIsConnected); %Stop build state timer
            end
            delete(app.timerCheckFlightMode);
            delete(app.timerCheckBuildState);
            delete(app.timerCheckBoardIsConnected);
            %Now the figures must be closed
            figuresOpenned = get(groot);
            if ~isempty(figuresOpenned)
                for i=1:length(figuresOpenned.Children)
                    if (strcmp(figuresOpenned.Children(i).Name,'UIControllers') == 1)
                        close('UIControllers')
                    
                    elseif (strcmp(figuresOpenned.Children(i).Name,'UIControlModes') == 1)
                        close('UIControlModes')
                    
                    elseif (strcmp(figuresOpenned.Children(i).Name,'UIFilters') == 1)
                        close('UIFilters')
                    end
                end
            end
            delete(app);         
        end

        % Button pushed function: TotalStationButton
        function total_station_launch_fcn(app, event)
            system([app.commandLaunchTotalStation,' &']);
        end

        % Callback function
        function launch_fcn(app, event)
            
            %%%%%%%Check Flight mode Timer%%%%%%%%
            if (isvalid(app.timerCheckFlightMode) && strcmp(app.timerCheckFlightMode.Running,'on') == 0)
                stop(app.timerCheckBoardIsConnected);
                start(app.timerCheckFlightMode); %Start the timer
            end
            if ~isBridgeLaunched()
                system([app.commandLaunchBridge,'&']);
            end
            system([app.commandLaunchPX4,'&']);
            
            system([app.commandLaunchExecutables,'&']);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.stateConnection = 1;
            %app.stateButtons = {'on','off','off','on','off','off','off','on','on','on','on','on','on','on','on','on'}; %Variable used for update the state of the buttons
            %updateButtonsState(app);
        end

        % Callback function
        function build_fcn(app, event)
            %%%%%%%Check Build Timer%%%%%%%%
            if (isvalid(app.timerCheckBuildState) && strcmp(app.timerCheckBuildState.Running,'on') == 0)
                if(exist(app.pathSlprj, 'dir') )
                    rmdir(app.pathSlprj,'s');
                end
                if (isvalid(app.timerCheckBoardIsConnected) && strcmp(app.timerCheckBoardIsConnected.Running,'on') == 1)
                    stop(app.timerCheckBoardIsConnected); %Stop build state timer
                end
                
                start(app.timerCheckBuildState);%Start the timer
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%Open Diagnostic viewer to obtain The failures' diary of simulink compilation  
            sldiagviewer.diary
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%Update Button state%%%%%%%%%%%%%%%%%%%%%%%%
            app.ModeLabel.Text = 'Building from simulink...';
            app.stateButtons ={'off','off','off','off','off','off','off','off','off','off','off','off','off','off','off','off'};
            updateButtonsState(app);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            save_system(strcat(app.nameModelSimulink,'.slx')); %Save the model
            set_param(app.nameModelSimulink, 'SimulationCommand', 'start'); %Start the compilation process
            app.stateBuild = 1; 
        end

        % Button pushed function: GeneratePlotButton
        function generate_plot_fcn(app, event)
            app.plotFigure = app.plotFigure + 1;
            if(strcmp(get_param(bdroot,'simulationstatus'),'stopped') == 1)
                 if (strcmp(app.SelectLogDropDown.Items,'No logs') == 0)
                     switch app.SelectsignalDropDown.Value
                        case 'Roll'
                            num = [2,48];
                            scale = 180/pi;
                            ylabel_plot = 'Roll [deg]';
                            legend_plot = {'Roll','Roll desired'};
                            title_plot = 'ROLL';
                        case 'Pitch'
                            num = [3,49];
                            scale = 180/pi;
                            ylabel_plot = 'Pitch [deg]';
                            legend_plot = {'Pitch','Pitch desired'};
                            title_plot = 'PITCH';
                        case 'Yaw'
                            num = [4,50];
                            scale = 180/pi;
                            ylabel_plot = 'Yaw [deg]';
                            legend_plot = {'Yaw','Yaw desired'};
                            title_plot = 'YAW';
                        case 'dRoll'
                            num = [5,51];
                            scale = 180/pi;
                            ylabel_plot = 'dRoll [deg/s]';
                            legend_plot = {'dRoll','dRoll desired'};
                            title_plot = 'DROLL';
                        case 'dPitch'
                            num = [6,52];
                            scale = 180/pi;
                            ylabel_plot = 'dPitch [deg/s]';
                            legend_plot = {'dPitch','dPitch desired'};
                            title_plot = 'DPITCH';
                        case 'dYaw'
                            num = [7,53];
                            scale = 180/pi;
                            ylabel_plot = 'dYaw [deg/s]';
                            legend_plot = {'dYaw','dYaw desired'};
                            title_plot = 'DYAW';
                        case 'X'
                            num = [30,37,41];
                            scale = 1;
                            ylabel_plot = 'X [m]';
                            legend_plot = {'X','X desired','X TotalStation'};
                            title_plot = 'X';
                        case 'Y'
                            num = [31,38,42];
                            scale = 1;
                            ylabel_plot = 'Y [m]';
                            legend_plot = {'Y','Y desired','Y TotalStation'};
                            title_plot = 'Y';
                        case 'Z'
                            num = [32,36,38,46];
                            scale = 1;
                            ylabel_plot = 'Z [m]';
                            legend_plot = {'Z','Z desired','Z TotalStation' , 'Z sf11'};
                            title_plot = 'Z';
                        case 'dX'
                            num = [33,43];
                            scale = 1;
                            ylabel_plot = 'dX [m/s]';
                            legend_plot = {'dX','dX desired'};
                            title_plot = 'DX';
                        case 'dY'
                            num = [34,44];
                            scale = 1;
                            ylabel_plot = 'dY [m/s]';
                            legend_plot = {'dY','dY desired'};
                            title_plot = 'DY';
                        case 'dZ'
                            num = [35,47];
                            scale = 1;
                            ylabel_plot = 'dZ [m/s]';
                            legend_plot = {'dZ','dZ desired'};
                            title_plot = 'DZ';
                        case 'Rotors'
                            num = [54,55,56,57];
                            scale = 1;                            
                            ylabel_plot = 'Rotors [PWM]';
                            legend_plot = {'Rotor1','Rotor2','Rotor3','Rotor4'};
                            title_plot = 'ROTORS';
                        case 'Gyro_mpu'
                            num = [11,12,13];
                            scale = 1;
                            ylabel_plot = 'Gyro MPU9250 [rad/s]';
                            legend_plot = {'g_x','g_y','g_z'};
                            title_plot = 'Gyro MPU9250';
                        case 'Accel_mpu'
                            num = [14,15,16];
                            scale = 1;
                            ylabel_plot = 'Accel MPU9250 [m/s^2]';
                            legend_plot = {'a_x','a_y','a_z'};
                            title_plot = 'Accel MPU9250';
                        case 'Magnet_mpu'
                            num = [17,18,19];
                            scale = 1;
                            ylabel_plot = 'Magnet MPU9250';
                            legend_plot = {'m_x','m_y','m_z'};
                            title_plot = 'Magnet MPU9250';
                        case 'Gyro_lsm'
                            num = [20,21,22];
                            scale = 1;
                            ylabel_plot = 'Gyro LSM9DS1 [rad/s]';
                            legend_plot = {'g_x','g_y','g_z'};
                            title_plot = 'Gyro LSM9DS1';
                        case 'Accel_lsm'
                            num = [23,24,25];
                            scale = 1;
                            ylabel_plot = 'Accel LSM9DS1 [m/s^2]';
                            legend_plot = {'a_x','a_y','a_z'};
                            title_plot = 'Accel LSM9DS1';
                        case 'Magnet_lsm'
                            num = [26,26,27];
                            scale = 1;
                            ylabel_plot = 'Magnet LSM9DS1';
                            legend_plot = {'m_x','m_y','m_z'};
                            title_plot = 'Magnet LSM9DS1';
                        case 'Channels'
                            num = [58,59,60,61,62,63,64,65,66,67];
                            scale = 1;
                            ylabel_plot = 'Channels [PWM]';
                            legend_plot = {'Channel1','Channel2','Channel3','Channel4','Channel5','Channel6','Channel7','Channel8','Channel9','Channel10'};
                            title_plot = 'CHANNELS';
                        otherwise
                            num = [2,48];
                            scale = 180/pi;
                            ylabel_plot = 'Roll [deg]';
                            legend_plot = {'Roll','Roll desired'};
                            title_plot = 'ROLL';
                    end
                    %figplot = uifigure('Name',title_plot,'Visible','on');                   
                    if ~isempty(app.logData)
                        figure(app.plotFigure);
                        hold on
                        for i=1:length(num) 
                                plot(app.logData.Data(:,1),app.logData.Data(:,num(i))*scale),  
                                
                        end
                        legend(legend_plot),
                        title(title_plot),
                        xlabel('time [s]'),
                        ylabel(ylabel_plot),
                    end
                    grid on,
                    hold off
                 end 
            end
        end

        % Callback function: SelectLogDropDown, UploadButton
        function update_logs_fcn(app, event)
            updateLogFiles(app);
            app.logSeleted = app.SelectLogDropDown.Value;
            if(strcmp(get_param(bdroot,'simulationstatus'),'stopped') == 1)
                if app.numFlights > 0
                    app.ModeLabel.Text = 'Downloading Logs...';
                    app.ModeLabel.FontColor = 'b';
                    getLogs();
                    app.numFlights = app.numFlights - 1;
                    app.ModeLabel.Text = 'Waiting...';
                    app.ModeLabel.FontColor = 'k';
                end
                if (strcmp(app.SelectLogDropDown.Items,'No logs') == 0)
                    app.logData = load(strcat(app.pathLogs,app.logSeleted));
                end 
            end
        end

        % Button pushed function: ControllersButton
        function call_controllers_interface(app, event)
            figuresOpenned = get(groot);
            found = 0;
            if ~isempty(figuresOpenned)
                for i=1:length(figuresOpenned.Children)
                    if (strcmp(figuresOpenned.Children(i).Name,'UIControllers') == 1)
                       found = 1;
                       break;
                    end
                end
            end
            if (found == 0)
                run appControllers.mlapp;
            end
        end

        % Button pushed function: ControlmodesButton
        function call_control_modes_interface(app, event)
            figuresOpenned = get(groot);
            found = 0;
            if ~isempty(figuresOpenned)
                for i=1:length(figuresOpenned.Children)
                    if (strcmp(figuresOpenned.Children(i).Name,'UIControlModes') == 1)
                       found = 1;
                       break;
                    end
                end
            end
            if (found == 0)
                run appControlModes.mlapp;
            end
            
        end

        % Button pushed function: ShutdownRaspiButton
        function shutdown_fcn(app, event)
           app.ConnectionLabel.Text = 'Shutting Down...';
           app.ConnectionLabel.FontColor = 'r';
           system([app.commandShutdownRaspi,'&']);
           app.stateRaspi = -1;
           %shutDownRaspi();
        end

        % Button pushed function: PX4Button
        function px4_launch_fcn(app, event)
           system([app.commandLaunchPX4,'&']);
        end

        % Button pushed function: XButton
        function kill_fcn(app, event)
            if(strcmp(get_param(bdroot,'simulationstatus'),'stopped') == 1)           
                %system(app.commandKillExecutables);
                killALL();
            end
            app.stateConnection = 0;
        end

        % Button pushed function: Button
        function check_connection_fcn(app, event)
            if (isvalid(app.timerCheckBoardIsConnected) && strcmp(app.timerCheckBoardIsConnected.Running,'off') == 1)
                start(app.timerCheckBoardIsConnected);
                app.numDisconnectedChecked = 0;
            end
        end

        % Button pushed function: FiltersButton
        function call_filters_interface(app, event)
            figuresOpenned = get(groot);
            found = 0;
            if ~isempty(figuresOpenned)
                for i=1:length(figuresOpenned.Children)
                    if (strcmp(figuresOpenned.Children(i).Name,'UIFilters') == 1)
                       found = 1;
                       break;
                    end
                end
            end
            if (found == 0)
                run appFilters.mlapp;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIAerobiUIFigure = uifigure;
            app.UIAerobiUIFigure.Name = 'UIAerobi';
            app.UIAerobiUIFigure.CloseRequestFcn = createCallbackFcn(app, @close_fcn, true);
            app.RealTimeExternalcontrolPanel = uipanel(app.UIAerobiUIFigure);
            app.RealTimeExternalcontrolPanel.Title = 'Real-Time External control:';
            app.ConnectButton = uibutton(app.RealTimeExternalcontrolPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @connect_disconnect_fcn, true);
            app.ConnectButton.Text = 'Connect';
            app.BuildButton = uibutton(app.RealTimeExternalcontrolPanel, 'push');
            app.BuildButton.ButtonPushedFcn = createCallbackFcn(app, @build_fcn, true);
            app.BuildButton.Text = 'Build';
            app.LaunchButton = uibutton(app.RealTimeExternalcontrolPanel, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @launch_fcn, true);
            app.LaunchButton.Text = 'Launch';
            app.TotalStationButton = uibutton(app.RealTimeExternalcontrolPanel, 'push');
            app.TotalStationButton.ButtonPushedFcn = createCallbackFcn(app, @total_station_launch_fcn, true);
            app.TotalStationButton.Text = 'Total Station';
            app.StopButton = uibutton(app.RealTimeExternalcontrolPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stop_simulation, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.RealTimeExternalcontrolPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start_simulation, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.ShutdownRaspiButton = uibutton(app.RealTimeExternalcontrolPanel, 'push');
            app.ShutdownRaspiButton.ButtonPushedFcn = createCallbackFcn(app, @shutdown_fcn, true);
            app.ShutdownRaspiButton.Text = 'Shutdown Raspi';
            app.PX4Button = uibutton(app.RealTimeExternalcontrolPanel, 'push');
            app.PX4Button.ButtonPushedFcn = createCallbackFcn(app, @px4_launch_fcn, true);
            app.PX4Button.Text = 'PX4';
            app.StatusPanel = uipanel(app.UIAerobiUIFigure);
            app.StatusPanel.Title = 'Status:';
            app.ModeLabel = uilabel(app.StatusPanel);
            app.ModeLabel.Text = 'Mode';
            app.PlotlogsPanel = uipanel(app.UIAerobiUIFigure);
            app.PlotlogsPanel.Title = 'Plot logs:';
            app.SelectsignalDropDownLabel = uilabel(app.PlotlogsPanel);
            app.SelectsignalDropDownLabel.Text = 'Select signal:';
            app.SelectsignalDropDown = uidropdown(app.PlotlogsPanel);
            app.SelectsignalDropDown.Items = {'Roll', 'Pitch', 'Yaw', 'dRoll', 'dPitch', 'dYaw', 'X', 'Y', 'Z', 'dX', 'dY', 'dZ', 'Rotors', 'Gyro_mpu', 'Accel_mpu', 'Magnet_mpu', 'Gyro_lsm', 'Accel_lsm', 'Magnet_lsm', 'Channels'};
            app.SelectsignalDropDown.Value = 'Roll';
            app.GeneratePlotButton = uibutton(app.PlotlogsPanel, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @generate_plot_fcn, true);
            app.GeneratePlotButton.Text = 'Generate Plot';
            app.SelectLogLabel = uilabel(app.PlotlogsPanel);
            app.SelectLogLabel.Text = 'Select Log:   ';
            app.SelectLogDropDown = uidropdown(app.PlotlogsPanel);
            app.SelectLogDropDown.Items = {'No logs'};
            app.SelectLogDropDown.ValueChangedFcn = createCallbackFcn(app, @update_logs_fcn, true);
            app.SelectLogDropDown.Value = 'No logs';
            app.UploadButton = uibutton(app.PlotlogsPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @update_logs_fcn, true);
            app.UploadButton.Text = 'Upload';
            app.ParametersPanel = uipanel(app.UIAerobiUIFigure);
            app.ParametersPanel.Title = 'Parameters:';
            app.ControllersButton = uibutton(app.ParametersPanel, 'push');
            app.ControllersButton.ButtonPushedFcn = createCallbackFcn(app, @call_controllers_interface, true);
            app.ControllersButton.Enable = 'off';
            app.ControllersButton.Text = 'Controllers';
            app.FiltersButton = uibutton(app.ParametersPanel, 'push');
            app.FiltersButton.ButtonPushedFcn = createCallbackFcn(app, @call_filters_interface, true);
            app.FiltersButton.Enable = 'off';
            app.FiltersButton.Text = 'Filters';
            app.ControlmodesButton = uibutton(app.ParametersPanel, 'push');
            app.ControlmodesButton.ButtonPushedFcn = createCallbackFcn(app, @call_control_modes_interface, true);
            app.ControlmodesButton.Text = 'Control modes';
            app.XButton = uibutton(app.UIAerobiUIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @kill_fcn, true);
            app.XButton.Text = 'X';
            app.ConnectionLabel = uilabel(app.UIAerobiUIFigure);
            app.ConnectionLabel.Text = 'Connection';
            app.Button = uibutton(app.UIAerobiUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @check_connection_fcn, true);
            app.Button.Icon = 'connection.jpeg';
            app.Button.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appAutopilot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIAerobiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @start_fcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIAerobiUIFigure)
        end
    end
end