classdef appControlModes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIControlModesUIFigure   matlab.ui.Figure
        FlightModesPanel         matlab.ui.container.Panel
        PositiondesiredPanel     matlab.ui.container.Panel
        TakeOffButton            matlab.ui.control.Button
        XmEditFieldLabel         matlab.ui.control.Label
        XmEditField              matlab.ui.control.NumericEditField
        YmEditFieldLabel         matlab.ui.control.Label
        YmEditField              matlab.ui.control.NumericEditField
        ZmEditFieldLabel         matlab.ui.control.Label
        ZmEditField              matlab.ui.control.NumericEditField
        YawdegEditFieldLabel     matlab.ui.control.Label
        YawdegEditField          matlab.ui.control.NumericEditField
        SendButton               matlab.ui.control.Button
        UpdateButton             matlab.ui.control.Button
        AttitudeLabel            matlab.ui.control.Label
        AttitudeDropDown         matlab.ui.control.DropDown
        AltitudeLabel            matlab.ui.control.Label
        AltitudeDropDown         matlab.ui.control.DropDown
        PositionLabel            matlab.ui.control.Label
        PositionDropDown         matlab.ui.control.DropDown
        ControllerDropDownLabel  matlab.ui.control.Label
        ControllerDropDown       matlab.ui.control.DropDown
    end


    properties (Access = private)
        nameModelSimulink % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [~,app.nameModelSimulink,~,~,~] = generatePathandNames();
            attitudeValue = str2double(get_param([app.nameModelSimulink,'/State/flight_modes/stabilized/stabilized_mode'],'Value'));
            switch attitudeValue
                case 1
                    app.AttitudeDropDown.Value = 'Full-Rate mode';               
                case 2
                    app.AttitudeDropDown.Value = 'Yaw rate mode';                   
                case 3
                    app.AttitudeDropDown.Value = 'Full-Stabilized mode';                    
                otherwise
                    app.AttitudeDropDown.Value = 'Full-Stabilized mode';            
            end 
            altitudeValue = str2double(get_param([app.nameModelSimulink,'/State/flight_modes/altitude/altitude_mode'],'Value'));
            switch altitudeValue
                case 1
                    app.AltitudeDropDown.Value = 'Velocity mode';               
                case 2
                    app.AltitudeDropDown.Value = 'Position mode';                                      
                otherwise
                    app.AltitudeDropDown.Value = 'Velocity mode'; 
            end
            positionValue = str2double(get_param([app.nameModelSimulink,'/State/flight_modes/position/position_mode'],'Value'));
            switch positionValue
                case 1
                    app.PositionDropDown.Value = 'Velocity mode';
                    app.PositiondesiredPanel.Visible = 'off';
                case 2
                    app.PositionDropDown.Value = 'Position mode';
                    app.PositiondesiredPanel.Visible = 'off';
                case 3
                    app.PositionDropDown.Value = 'Autonomous mode';
                    app.PositiondesiredPanel.Visible = 'on';
                otherwise
                    app.PositionDropDown.Value = 'Velocity mode';
                    app.PositiondesiredPanel.Visible = 'off';
            end
            controllerValue = str2double(get_param([app.nameModelSimulink,'/State/Controller_Selected/type_controller'],'Value'));
            switch controllerValue
                case 1
                    app.ControllerDropDown.Value = 'PID cascade';
                case 2
                    app.ControllerDropDown.Value = 'Geometric';
                case 3
                    app.ControllerDropDown.Value = 'Integral Backstepping';
                otherwise
                    app.ControllerDropDown.Value = 'PID cascade';
            end
        end

        % Close request function: UIControlModesUIFigure
        function UIControlModesUIFigureCloseRequest(app, event)
            delete(app);


        end

        % Button pushed function: UpdateButton
        function update_fcn(app, event)
            %[~,app.nameModelSimulink,~,~,~] = generatePathandNames();


            app.XmEditField.Value = str2double(get_param([app.nameModelSimulink,'/State/references_PC/Xdes'],'Value'));
            app.YmEditField.Value = str2double(get_param([app.nameModelSimulink,'/State/references_PC/Ydes'],'Value'));
            app.ZmEditField.Value = str2double(get_param([app.nameModelSimulink,'/State/references_PC/Zdes'],'Value'));
            app.YawdegEditField.Value = str2double(get_param([app.nameModelSimulink,'/State/references_PC/YAWdes'],'Value'));
            
            if app.PositionDropDown.Value == 3
                app.PositiondesiredPanel.Visible = 'on';
            else
                app.PositiondesiredPanel.Visible = 'off';
            end


        end

        % Value changed function: AttitudeDropDown
        function attitude_modes(app, event)
            value = app.AttitudeDropDown.Value;
            switch value
                case 'Full-Rate mode'
                    set_param([app.nameModelSimulink,'/State/flight_modes/stabilized/stabilized_mode'],'Value','1');
                    
                case 'Yaw rate mode'
                    set_param([app.nameModelSimulink,'/State/flight_modes/stabilized/stabilized_mode'],'Value','2');
                    
                case 'Full-Stabilized mode'
                    set_param([app.nameModelSimulink,'/State/flight_modes/stabilized/stabilized_mode'],'Value','3');
                    
                otherwise
                    set_param([app.nameModelSimulink,'/State/flight_modes/stabilized/stabilized_mode'],'Value','1');
                    
            end   
            
        end

        % Value changed function: AltitudeDropDown
        function altitude_modes(app, event)
            value = app.AltitudeDropDown.Value;
            switch value
                case 'Velocity mode'
                    set_param([app.nameModelSimulink,'/State/flight_modes/altitude/altitude_mode'],'Value','1');
                    
                case 'Position mode'
                    set_param([app.nameModelSimulink,'/State/flight_modes/altitude/altitude_mode'],'Value','2');
                    
                otherwise
                    set_param([app.nameModelSimulink,'/State/flight_modes/altitude/altitude_mode'],'Value','1');
                    
            end
        
%             set_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/geometricControl'],'Value',num2str(app.EnableGeometricControlCheckBox.Value));
%             set_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/rollPitch_pos_rate'],'Value',num2str(app.RollandPitchstabilizemodeCheckBox.Value));
%             set_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/yaw_pos_rate'],'Value',num2str(app.YawstabilizemodeCheckBox.Value));
%             set_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/XY_pos_vel'],'Value',num2str(app.XandYpositionmodeCheckBox.Value));
%             set_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/Z_pos_vel'],'Value',num2str(app.ZpositionmodeCheckBox.Value));
%             set_param([app.nameModelSimulink,'/Sensors/control_modes/ModeBlock/enable_PC'],'Value',num2str(app.EnablepositionreferencesfromPCCheckBox.Value));

            
        end

        % Value changed function: PositionDropDown
        function position_modes(app, event)
            value = app.PositionDropDown.Value;
            switch value
                case 'Velocity mode'
                    set_param([app.nameModelSimulink,'/State/flight_modes/position/position_mode'],'Value','1');
                    app.PositiondesiredPanel.Visible = 'off';
                    
                case 'Position mode'
                    set_param([app.nameModelSimulink,'/State/flight_modes/position/position_mode'],'Value','2');
                    app.PositiondesiredPanel.Visible = 'off';
                    
                case 'Autonomous mode'
                    set_param([app.nameModelSimulink,'/State/flight_modes/position/position_mode'],'Value','3');
                    app.PositiondesiredPanel.Visible = 'on';
                otherwise
                    set_param([app.nameModelSimulink,'/State/flight_modes/position/position_mode'],'Value','1');
                    app.PositiondesiredPanel.Visible = 'off';
                    
            end

        end

        % Value changed function: ControllerDropDown
        function select_controller(app, event)
            value = app.ControllerDropDown.Value;
            switch value
                case 'PID cascade'
                    set_param([app.nameModelSimulink,'/State/Controller_Selected/type_controller'],'Value','1');

                    
                case 'Geometric'
                    set_param([app.nameModelSimulink,'/State/Controller_Selected/type_controller'],'Value','2');

                    
                case 'Integral Backstepping'
                    set_param([app.nameModelSimulink,'/State/Controller_Selected/type_controller'],'Value','3');

                otherwise
                    set_param([app.nameModelSimulink,'/State/Controller_Selected/type_controller'],'Value','1');

                    
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIControlModesUIFigure = uifigure;
            app.UIControlModesUIFigure.Name = 'UIControlModes';
            app.UIControlModesUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIControlModesUIFigureCloseRequest, true);
            app.FlightModesPanel = uipanel(app.UIControlModesUIFigure);
            app.FlightModesPanel.Title = 'Flight Modes:';
            app.PositiondesiredPanel = uipanel(app.FlightModesPanel);
            app.PositiondesiredPanel.Title = 'Position desired:';
            app.TakeOffButton = uibutton(app.PositiondesiredPanel, 'push');
            app.TakeOffButton.Text = 'Take Off';
            app.XmEditFieldLabel = uilabel(app.PositiondesiredPanel);
            app.XmEditFieldLabel.Text = 'X [m]:';
            app.XmEditField = uieditfield(app.PositiondesiredPanel, 'numeric');
            app.YmEditFieldLabel = uilabel(app.PositiondesiredPanel);
            app.YmEditFieldLabel.Text = 'Y [m]:';
            app.YmEditField = uieditfield(app.PositiondesiredPanel, 'numeric');
            app.ZmEditFieldLabel = uilabel(app.PositiondesiredPanel);
            app.ZmEditFieldLabel.Text = 'Z [m]:';
            app.ZmEditField = uieditfield(app.PositiondesiredPanel, 'numeric');
            app.YawdegEditFieldLabel = uilabel(app.PositiondesiredPanel);
            app.YawdegEditFieldLabel.Text = 'Yaw [deg]:';
            app.YawdegEditField = uieditfield(app.PositiondesiredPanel, 'numeric');
            app.SendButton = uibutton(app.PositiondesiredPanel, 'push');
            app.SendButton.Text = 'Send';
            app.UpdateButton = uibutton(app.FlightModesPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @update_fcn, true);
            app.UpdateButton.Text = 'Update';
            app.AttitudeLabel = uilabel(app.FlightModesPanel);
            app.AttitudeLabel.Text = 'Attitude:';
            app.AttitudeDropDown = uidropdown(app.FlightModesPanel);
            app.AttitudeDropDown.Items = {'Full-Rate mode', 'Yaw rate mode', 'Full-Stabilized mode'};
            app.AttitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @attitude_modes, true);
            app.AttitudeDropDown.Value = 'Full-Rate mode';
            app.AltitudeLabel = uilabel(app.FlightModesPanel);
            app.AltitudeLabel.Text = 'Altitude:';
            app.AltitudeDropDown = uidropdown(app.FlightModesPanel);
            app.AltitudeDropDown.Items = {'Velocity mode', 'Position mode'};
            app.AltitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @altitude_modes, true);
            app.AltitudeDropDown.Value = 'Velocity mode';
            app.PositionLabel = uilabel(app.FlightModesPanel);
            app.PositionLabel.Text = 'Position:';
            app.PositionDropDown = uidropdown(app.FlightModesPanel);
            app.PositionDropDown.Items = {'Velocity mode', 'Position mode', 'Autonomous mode'};
            app.PositionDropDown.ValueChangedFcn = createCallbackFcn(app, @position_modes, true);
            app.PositionDropDown.Value = 'Velocity mode';
            app.ControllerDropDownLabel = uilabel(app.FlightModesPanel);
            app.ControllerDropDownLabel.Text = 'Controller:';
            app.ControllerDropDown = uidropdown(app.FlightModesPanel);
            app.ControllerDropDown.Items = {'PID cascade', 'Geometric', 'Integral Backstepping'};
            app.ControllerDropDown.ValueChangedFcn = createCallbackFcn(app, @select_controller, true);
            app.ControllerDropDown.Value = 'PID cascade';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appControlModes

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIControlModesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIControlModesUIFigure)
        end
    end
end