classdef appControllers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIControllersUIFigure           matlab.ui.Figure
        ControllersPanel                matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        PIDControlTab                   matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        RollPitchTab                    matlab.ui.container.Tab
        PositionControlPanel_RollPitch  matlab.ui.container.Panel
        GainPEditFieldLabel             matlab.ui.control.Label
        GainPEditField_RollPitch_pos    matlab.ui.control.NumericEditField
        GainIEditFieldLabel             matlab.ui.control.Label
        GainIEditField_RollPitch_pos    matlab.ui.control.NumericEditField
        GainDEditFieldLabel             matlab.ui.control.Label
        GainDEditField_RollPitch_pos    matlab.ui.control.NumericEditField
        SatEditFieldLabel               matlab.ui.control.Label
        SatPosEditField_RollPitch_pos   matlab.ui.control.NumericEditField
        SatEditField_2Label             matlab.ui.control.Label
        SatNegEditField_RollPitch_pos   matlab.ui.control.NumericEditField
        RateControlPanel_RollPitch      matlab.ui.container.Panel
        GainPEditField_2Label           matlab.ui.control.Label
        GainPEditField_RollPitch_rate   matlab.ui.control.NumericEditField
        GainIEditField_2Label           matlab.ui.control.Label
        GainIEditField_RollPitch_rate   matlab.ui.control.NumericEditField
        GainDEditField_2Label           matlab.ui.control.Label
        GainDEditField_RollPitch_rate   matlab.ui.control.NumericEditField
        SatEditField_3Label             matlab.ui.control.Label
        SatPosEditField_RollPitch_rate  matlab.ui.control.NumericEditField
        SatEditField_4Label             matlab.ui.control.Label
        SatNegEditField_RollPitch_rate  matlab.ui.control.NumericEditField
        YawTab                          matlab.ui.container.Tab
        PositionControlPanel_Yaw        matlab.ui.container.Panel
        GainPEditField_5Label           matlab.ui.control.Label
        GainPEditField_Yaw_pos          matlab.ui.control.NumericEditField
        GainIEditField_5Label           matlab.ui.control.Label
        GainIEditField_Yaw_pos          matlab.ui.control.NumericEditField
        GainDEditField_5Label           matlab.ui.control.Label
        GainDEditField_Yaw_pos          matlab.ui.control.NumericEditField
        SatEditField_9Label             matlab.ui.control.Label
        SatPosEditField_Yaw_pos         matlab.ui.control.NumericEditField
        SatEditField_10Label            matlab.ui.control.Label
        SatNegEditField_Yaw_pos         matlab.ui.control.NumericEditField
        RateControlPanel_Yaw            matlab.ui.container.Panel
        GainPEditField_6Label           matlab.ui.control.Label
        GainPEditField_Yaw_rate         matlab.ui.control.NumericEditField
        GainIEditField_6Label           matlab.ui.control.Label
        GainIEditField_Yaw_rate         matlab.ui.control.NumericEditField
        GainDEditField_6Label           matlab.ui.control.Label
        GainDEditField_Yaw_rate         matlab.ui.control.NumericEditField
        SatEditField_11Label            matlab.ui.control.Label
        SatPosEditField_Yaw_rate        matlab.ui.control.NumericEditField
        SatEditField_12Label            matlab.ui.control.Label
        SatNegEditField_Yaw_rate        matlab.ui.control.NumericEditField
        XYTab                           matlab.ui.container.Tab
        PositionControlPanel_XY         matlab.ui.container.Panel
        GainPEditField_7Label           matlab.ui.control.Label
        GainPEditField_XY_pos           matlab.ui.control.NumericEditField
        GainIEditField_7Label           matlab.ui.control.Label
        GainIEditField_XY_pos           matlab.ui.control.NumericEditField
        GainDEditField_7Label           matlab.ui.control.Label
        GainDEditField_XY_pos           matlab.ui.control.NumericEditField
        SatEditField_13Label            matlab.ui.control.Label
        SatPosEditField_XY_pos          matlab.ui.control.NumericEditField
        SatEditField_14Label            matlab.ui.control.Label
        SatNegEditField_XY_pos          matlab.ui.control.NumericEditField
        RateControlPanel_XY             matlab.ui.container.Panel
        GainPEditField_8Label           matlab.ui.control.Label
        GainPEditField_XY_rate          matlab.ui.control.NumericEditField
        GainIEditField_8Label           matlab.ui.control.Label
        GainIEditField_XY_rate          matlab.ui.control.NumericEditField
        GainDEditField_8Label           matlab.ui.control.Label
        GainDEditField_XY_rate          matlab.ui.control.NumericEditField
        SatEditField_15Label            matlab.ui.control.Label
        SatPosEditField_XY_rate         matlab.ui.control.NumericEditField
        SatEditField_16Label            matlab.ui.control.Label
        SatNegEditField_XY_rate         matlab.ui.control.NumericEditField
        ZTab                            matlab.ui.container.Tab
        PositionControlPanel_Z          matlab.ui.container.Panel
        GainPEditField_11Label          matlab.ui.control.Label
        GainPEditField_Z_pos            matlab.ui.control.NumericEditField
        GainIEditField_11Label          matlab.ui.control.Label
        GainIEditField_Z_pos            matlab.ui.control.NumericEditField
        GainDEditField_11Label          matlab.ui.control.Label
        GainDEditField_Z_pos            matlab.ui.control.NumericEditField
        SatEditField_21Label            matlab.ui.control.Label
        SatPosEditField_Z_pos           matlab.ui.control.NumericEditField
        SatEditField_22Label            matlab.ui.control.Label
        SatNegEditField_Z_pos           matlab.ui.control.NumericEditField
        RateControlPanel_Z              matlab.ui.container.Panel
        GainPEditField_12Label          matlab.ui.control.Label
        GainPEditField_Z_rate           matlab.ui.control.NumericEditField
        GainIEditField_12Label          matlab.ui.control.Label
        GainIEditField_Z_rate           matlab.ui.control.NumericEditField
        GainDEditField_12Label          matlab.ui.control.Label
        GainDEditField_Z_rate           matlab.ui.control.NumericEditField
        SatEditField_23Label            matlab.ui.control.Label
        SatPosEditField_Z_rate          matlab.ui.control.NumericEditField
        SatEditField_24Label            matlab.ui.control.Label
        SatNegEditField_Z_rate          matlab.ui.control.NumericEditField
        GeometricControlTab             matlab.ui.container.Tab
        UAVparametersPanel              matlab.ui.container.Panel
        MassKgEditFieldLabel            matlab.ui.control.Label
        MassKgEditField                 matlab.ui.control.NumericEditField
        C_TfmEditFieldLabel             matlab.ui.control.Label
        C_TfmEditField                  matlab.ui.control.NumericEditField
        dmEditFieldLabel                matlab.ui.control.Label
        dmEditField                     matlab.ui.control.NumericEditField
        Jkgm2EditFieldLabel             matlab.ui.control.Label
        Jkgm2EditField                  matlab.ui.control.EditField
        AttitudegainsPanel              matlab.ui.container.Panel
        k_OEditFieldLabel               matlab.ui.control.Label
        k_OEditField                    matlab.ui.control.EditField
        k_REditField_3Label             matlab.ui.control.Label
        k_REditField                    matlab.ui.control.EditField
        k_IEditFieldLabel               matlab.ui.control.Label
        k_IEditField                    matlab.ui.control.EditField
        c2EditFieldLabel                matlab.ui.control.Label
        c2EditField                     matlab.ui.control.EditField
        PositiongainsPanel              matlab.ui.container.Panel
        k_xEditFieldLabel               matlab.ui.control.Label
        k_xEditField                    matlab.ui.control.EditField
        k_vEditFieldLabel               matlab.ui.control.Label
        k_vEditField                    matlab.ui.control.EditField
        k_iEditFieldLabel               matlab.ui.control.Label
        k_iEditField                    matlab.ui.control.EditField
        c1EditFieldLabel                matlab.ui.control.Label
        c1EditField                     matlab.ui.control.EditField
        UpdateparametersButton          matlab.ui.control.Button
    end


    properties (Access = private)
        nameModelSimulink
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [~,app.nameModelSimulink,~,~,~] = generatePathandNames();
            % Roll Pitch position gains
            app.GainPEditField_RollPitch_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_RollPitch_pos'],'Value'));
            app.GainIEditField_RollPitch_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_RollPitch_pos'],'Value'));
            app.GainDEditField_RollPitch_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_RollPitch_pos'],'Value'));
            app.SatPosEditField_RollPitch_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_RollPitch_pos'],'Value'));
            app.SatNegEditField_RollPitch_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_RollPitch_pos'],'Value'));
            % Roll Pitch rate gains
            app.GainPEditField_RollPitch_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_RollPitch_rate'],'Value'));
            app.GainIEditField_RollPitch_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_RollPitch_rate'],'Value'));
            app.GainDEditField_RollPitch_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_RollPitch_rate'],'Value'));
            app.SatPosEditField_RollPitch_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_RollPitch_rate'],'Value'));
            app.SatNegEditField_RollPitch_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_RollPitch_rate'],'Value'));
            % Yaw position gains
            app.GainPEditField_Yaw_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_Yaw_pos'],'Value'));
            app.GainIEditField_Yaw_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_Yaw_pos'],'Value'));
            app.GainDEditField_Yaw_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_Yaw_pos'],'Value'));
            app.SatPosEditField_Yaw_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_Yaw_pos'],'Value'));
            app.SatNegEditField_Yaw_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_Yaw_pos'],'Value'));           
            % Yaw rate gains
            app.GainPEditField_Yaw_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_Yaw_rate'],'Value'));
            app.GainIEditField_Yaw_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_Yaw_rate'],'Value'));
            app.GainDEditField_Yaw_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_Yaw_rate'],'Value'));
            app.SatPosEditField_Yaw_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_Yaw_rate'],'Value'));
            app.SatNegEditField_Yaw_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_Yaw_rate'],'Value'));  
             % XY position gains
            app.GainPEditField_XY_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_XY_pos'],'Value'));
            app.GainIEditField_XY_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_XY_pos'],'Value'));
            app.GainDEditField_XY_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_XY_pos'],'Value'));
            app.SatPosEditField_XY_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_XY_pos'],'Value'));
            app.SatNegEditField_XY_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_XY_pos'],'Value'));           
            % XY rate gains
            app.GainPEditField_XY_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_XY_rate'],'Value'));
            app.GainIEditField_XY_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_XY_rate'],'Value'));
            app.GainDEditField_XY_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_XY_rate'],'Value'));
            app.SatPosEditField_XY_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_XY_rate'],'Value'));
            app.SatNegEditField_XY_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_XY_rate'],'Value'));
            % Z position gains
            app.GainPEditField_Z_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_Z_pos'],'Value'));
            app.GainIEditField_Z_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_Z_pos'],'Value'));
            app.GainDEditField_Z_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_Z_pos'],'Value'));
            app.SatPosEditField_Z_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_Z_pos'],'Value'));
            app.SatNegEditField_Z_pos.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_Z_pos'],'Value'));           
            % Z rate gains
            app.GainPEditField_Z_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_Z_rate'],'Value'));
            app.GainIEditField_Z_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_Z_rate'],'Value'));
            app.GainDEditField_Z_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_Z_rate'],'Value'));
            app.SatPosEditField_Z_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_Z_rate'],'Value'));
            app.SatNegEditField_Z_rate.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_Z_rate'],'Value'));   
          %Geometric Controller
            app.Jkgm2EditField.Value = get_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/J'],'Value');
            app.MassKgEditField.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/Mass'],'Value'));
            app.C_TfmEditField.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/CTf'],'Value'));
            app.dmEditField.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/d'],'Value'));
            app.k_REditField.Value = get_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/k_R'],'Value');
            app.k_OEditField.Value = get_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/k_Omega'],'Value');
            app.k_IEditField.Value = get_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/k_I'],'Value');
            app.c2EditField.Value = get_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/c2'],'Value');
            app.k_vEditField.Value = get_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/k_v'],'Value');
            app.k_xEditField.Value = get_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/k_x'],'Value');
            app.k_iEditField.Value = get_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/k_i'],'Value');
            app.c1EditField.Value = get_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/c1'],'Value');
        end

        % Button pushed function: UpdateparametersButton
        function update_control_gains_fcn(app, event)
            % Change Roll Pitch position gain values
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_RollPitch_pos'],'Value',num2str(app.GainPEditField_RollPitch_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_RollPitch_pos'],'Value',num2str(app.GainIEditField_RollPitch_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_RollPitch_pos'],'Value',num2str(app.GainDEditField_RollPitch_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_RollPitch_pos'],'Value',num2str(app.SatPosEditField_RollPitch_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_RollPitch_pos'],'Value',num2str(app.SatNegEditField_RollPitch_pos.Value));
            % Change Roll Pitch rate gain values
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_RollPitch_rate'],'Value',num2str(app.GainPEditField_RollPitch_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_RollPitch_rate'],'Value',num2str(app.GainIEditField_RollPitch_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_RollPitch_rate'],'Value',num2str(app.GainDEditField_RollPitch_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_RollPitch_rate'],'Value',num2str(app.SatPosEditField_RollPitch_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_RollPitch_rate'],'Value',num2str(app.SatNegEditField_RollPitch_rate.Value));
            % Change Yaw position gain values
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_Yaw_pos'],'Value',num2str(app.GainPEditField_Yaw_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_Yaw_pos'],'Value',num2str(app.GainIEditField_Yaw_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_Yaw_pos'],'Value',num2str(app.GainDEditField_Yaw_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_Yaw_pos'],'Value',num2str(app.SatPosEditField_Yaw_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_Yaw_pos'],'Value',num2str(app.SatNegEditField_Yaw_pos.Value));
            % Change Yaw rate gain values
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_Yaw_rate'],'Value',num2str(app.GainPEditField_Yaw_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_Yaw_rate'],'Value',num2str(app.GainIEditField_Yaw_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_Yaw_rate'],'Value',num2str(app.GainDEditField_Yaw_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_Yaw_rate'],'Value',num2str(app.SatPosEditField_Yaw_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_Yaw_rate'],'Value',num2str(app.SatNegEditField_Yaw_rate.Value));   
            % Change XY position gain values
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_XY_pos'],'Value',num2str(app.GainPEditField_XY_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_XY_pos'],'Value',num2str(app.GainIEditField_XY_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_XY_pos'],'Value',num2str(app.GainDEditField_XY_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_XY_pos'],'Value',num2str(app.SatPosEditField_XY_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_XY_pos'],'Value',num2str(app.SatNegEditField_XY_pos.Value));
            % Change XY rate gain values
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_XY_rate'],'Value',num2str(app.GainPEditField_XY_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_XY_rate'],'Value',num2str(app.GainIEditField_XY_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_XY_rate'],'Value',num2str(app.GainDEditField_XY_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_XY_rate'],'Value',num2str(app.SatPosEditField_XY_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_XY_rate'],'Value',num2str(app.SatNegEditField_XY_rate.Value));
            % Change Z position gain values
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_Z_pos'],'Value',num2str(app.GainPEditField_Z_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_Z_pos'],'Value',num2str(app.GainIEditField_Z_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_Z_pos'],'Value',num2str(app.GainDEditField_Z_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_Z_pos'],'Value',num2str(app.SatPosEditField_Z_pos.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_Z_pos'],'Value',num2str(app.SatNegEditField_Z_pos.Value));
            % Change Z rate gain values
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/P_Z_rate'],'Value',num2str(app.GainPEditField_Z_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/I_Z_rate'],'Value',num2str(app.GainIEditField_Z_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/D_Z_rate'],'Value',num2str(app.GainDEditField_Z_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatPos_Z_rate'],'Value',num2str(app.SatPosEditField_Z_rate.Value));
            set_param([app.nameModelSimulink,'/Sensors/PID_cascade_gains/SatNeg_Z_rate'],'Value',num2str(app.SatNegEditField_Z_rate.Value)); 
         %Geometric Controller
            set_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/J'],'Value',app.Jkgm2EditField.Value);   
            set_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/Mass'],'Value',num2str(app.MassKgEditField.Value));
            set_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/d'],'Value',num2str(app.dmEditField.Value)); 
            set_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/CTf'],'Value',num2str(app.C_TfmEditField.Value));
            set_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/k_R'],'Value',app.k_REditField.Value);
            set_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/k_Omega'],'Value',app.k_OEditField.Value);
            set_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/k_I'],'Value',app.k_IEditField.Value);
            set_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/c2'],'Value',app.c2EditField.Value);
            set_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/k_x'],'Value',app.k_xEditField.Value);
            set_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/k_v'],'Value',app.k_vEditField.Value);
            set_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/k_i'],'Value',app.k_iEditField.Value);
            set_param([app.nameModelSimulink,'/Sensors/Geometric_Control_gains/c1'],'Value',app.c1EditField.Value);
        end

        % Close request function: UIControllersUIFigure
        function UIControllersUIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIControllersUIFigure = uifigure;
            app.UIControllersUIFigure.Name = 'UIControllers';
            app.UIControllersUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIControllersUIFigureCloseRequest, true);
            app.ControllersPanel = uipanel(app.UIControllersUIFigure);
            app.ControllersPanel.Title = 'Controllers:';
            app.TabGroup2 = uitabgroup(app.UIControllersUIFigure);
            app.PIDControlTab = uitab(app.TabGroup2);
            app.PIDControlTab.Title = 'PID Control';
            app.TabGroup = uitabgroup(app.PIDControlTab);
            app.RollPitchTab = uitab(app.TabGroup);
            app.RollPitchTab.Title = 'Roll/Pitch';
            app.PositionControlPanel_RollPitch = uipanel(app.RollPitchTab);
            app.PositionControlPanel_RollPitch.Title = 'Position Control:';
            app.GainPEditFieldLabel = uilabel(app.PositionControlPanel_RollPitch);
            app.GainPEditFieldLabel.Text = 'Gain P:';
            app.GainPEditField_RollPitch_pos = uieditfield(app.PositionControlPanel_RollPitch, 'numeric');
            app.GainPEditField_RollPitch_pos.Value = 1;
            app.GainIEditFieldLabel = uilabel(app.PositionControlPanel_RollPitch);
            app.GainIEditFieldLabel.Text = 'Gain I:';
            app.GainIEditField_RollPitch_pos = uieditfield(app.PositionControlPanel_RollPitch, 'numeric');
            app.GainDEditFieldLabel = uilabel(app.PositionControlPanel_RollPitch);
            app.GainDEditFieldLabel.Text = 'Gain D:';
            app.GainDEditField_RollPitch_pos = uieditfield(app.PositionControlPanel_RollPitch, 'numeric');
            app.SatEditFieldLabel = uilabel(app.PositionControlPanel_RollPitch);
            app.SatEditFieldLabel.Text = 'Sat +:';
            app.SatPosEditField_RollPitch_pos = uieditfield(app.PositionControlPanel_RollPitch, 'numeric');
            app.SatPosEditField_RollPitch_pos.Value = Inf;
            app.SatEditField_2Label = uilabel(app.PositionControlPanel_RollPitch);
            app.SatEditField_2Label.Text = 'Sat -:';
            app.SatNegEditField_RollPitch_pos = uieditfield(app.PositionControlPanel_RollPitch, 'numeric');
            app.SatNegEditField_RollPitch_pos.Value = -Inf;
            app.RateControlPanel_RollPitch = uipanel(app.RollPitchTab);
            app.RateControlPanel_RollPitch.Title = 'Rate Control:';
            app.GainPEditField_2Label = uilabel(app.RateControlPanel_RollPitch);
            app.GainPEditField_2Label.Text = 'Gain P:';
            app.GainPEditField_RollPitch_rate = uieditfield(app.RateControlPanel_RollPitch, 'numeric');
            app.GainPEditField_RollPitch_rate.Value = 1;
            app.GainIEditField_2Label = uilabel(app.RateControlPanel_RollPitch);
            app.GainIEditField_2Label.Text = 'Gain I:';
            app.GainIEditField_RollPitch_rate = uieditfield(app.RateControlPanel_RollPitch, 'numeric');
            app.GainDEditField_2Label = uilabel(app.RateControlPanel_RollPitch);
            app.GainDEditField_2Label.Text = 'Gain D:';
            app.GainDEditField_RollPitch_rate = uieditfield(app.RateControlPanel_RollPitch, 'numeric');
            app.SatEditField_3Label = uilabel(app.RateControlPanel_RollPitch);
            app.SatEditField_3Label.Text = 'Sat +:';
            app.SatPosEditField_RollPitch_rate = uieditfield(app.RateControlPanel_RollPitch, 'numeric');
            app.SatPosEditField_RollPitch_rate.Value = Inf;
            app.SatEditField_4Label = uilabel(app.RateControlPanel_RollPitch);
            app.SatEditField_4Label.Text = 'Sat -:';
            app.SatNegEditField_RollPitch_rate = uieditfield(app.RateControlPanel_RollPitch, 'numeric');
            app.SatNegEditField_RollPitch_rate.Value = -Inf;
            app.YawTab = uitab(app.TabGroup);
            app.YawTab.Title = 'Yaw';
            app.PositionControlPanel_Yaw = uipanel(app.YawTab);
            app.PositionControlPanel_Yaw.Title = 'Position Control:';
            app.GainPEditField_5Label = uilabel(app.PositionControlPanel_Yaw);
            app.GainPEditField_5Label.Text = 'Gain P:';
            app.GainPEditField_Yaw_pos = uieditfield(app.PositionControlPanel_Yaw, 'numeric');
            app.GainPEditField_Yaw_pos.Value = 1;
            app.GainIEditField_5Label = uilabel(app.PositionControlPanel_Yaw);
            app.GainIEditField_5Label.Text = 'Gain I:';
            app.GainIEditField_Yaw_pos = uieditfield(app.PositionControlPanel_Yaw, 'numeric');
            app.GainDEditField_5Label = uilabel(app.PositionControlPanel_Yaw);
            app.GainDEditField_5Label.Text = 'Gain D:';
            app.GainDEditField_Yaw_pos = uieditfield(app.PositionControlPanel_Yaw, 'numeric');
            app.SatEditField_9Label = uilabel(app.PositionControlPanel_Yaw);
            app.SatEditField_9Label.Text = 'Sat +:';
            app.SatPosEditField_Yaw_pos = uieditfield(app.PositionControlPanel_Yaw, 'numeric');
            app.SatPosEditField_Yaw_pos.Value = Inf;
            app.SatEditField_10Label = uilabel(app.PositionControlPanel_Yaw);
            app.SatEditField_10Label.Text = 'Sat -:';
            app.SatNegEditField_Yaw_pos = uieditfield(app.PositionControlPanel_Yaw, 'numeric');
            app.SatNegEditField_Yaw_pos.Value = -Inf;
            app.RateControlPanel_Yaw = uipanel(app.YawTab);
            app.RateControlPanel_Yaw.Title = 'Rate Control:';
            app.GainPEditField_6Label = uilabel(app.RateControlPanel_Yaw);
            app.GainPEditField_6Label.Text = 'Gain P:';
            app.GainPEditField_Yaw_rate = uieditfield(app.RateControlPanel_Yaw, 'numeric');
            app.GainPEditField_Yaw_rate.Value = 1;
            app.GainIEditField_6Label = uilabel(app.RateControlPanel_Yaw);
            app.GainIEditField_6Label.Text = 'Gain I:';
            app.GainIEditField_Yaw_rate = uieditfield(app.RateControlPanel_Yaw, 'numeric');
            app.GainDEditField_6Label = uilabel(app.RateControlPanel_Yaw);
            app.GainDEditField_6Label.Text = 'Gain D:';
            app.GainDEditField_Yaw_rate = uieditfield(app.RateControlPanel_Yaw, 'numeric');
            app.SatEditField_11Label = uilabel(app.RateControlPanel_Yaw);
            app.SatEditField_11Label.Text = 'Sat +:';
            app.SatPosEditField_Yaw_rate = uieditfield(app.RateControlPanel_Yaw, 'numeric');
            app.SatPosEditField_Yaw_rate.Value = Inf;
            app.SatEditField_12Label = uilabel(app.RateControlPanel_Yaw);
            app.SatEditField_12Label.Text = 'Sat -:';
            app.SatNegEditField_Yaw_rate = uieditfield(app.RateControlPanel_Yaw, 'numeric');
            app.SatNegEditField_Yaw_rate.Value = -Inf;
            app.XYTab = uitab(app.TabGroup);
            app.XYTab.Title = 'X/Y';
            app.PositionControlPanel_XY = uipanel(app.XYTab);
            app.PositionControlPanel_XY.Title = 'Position Control:';
            app.GainPEditField_7Label = uilabel(app.PositionControlPanel_XY);
            app.GainPEditField_7Label.Text = 'Gain P:';
            app.GainPEditField_XY_pos = uieditfield(app.PositionControlPanel_XY, 'numeric');
            app.GainPEditField_XY_pos.Value = 1;
            app.GainIEditField_7Label = uilabel(app.PositionControlPanel_XY);
            app.GainIEditField_7Label.Text = 'Gain I:';
            app.GainIEditField_XY_pos = uieditfield(app.PositionControlPanel_XY, 'numeric');
            app.GainDEditField_7Label = uilabel(app.PositionControlPanel_XY);
            app.GainDEditField_7Label.Text = 'Gain D:';
            app.GainDEditField_XY_pos = uieditfield(app.PositionControlPanel_XY, 'numeric');
            app.SatEditField_13Label = uilabel(app.PositionControlPanel_XY);
            app.SatEditField_13Label.Text = 'Sat +:';
            app.SatPosEditField_XY_pos = uieditfield(app.PositionControlPanel_XY, 'numeric');
            app.SatPosEditField_XY_pos.Value = Inf;
            app.SatEditField_14Label = uilabel(app.PositionControlPanel_XY);
            app.SatEditField_14Label.Text = 'Sat -:';
            app.SatNegEditField_XY_pos = uieditfield(app.PositionControlPanel_XY, 'numeric');
            app.SatNegEditField_XY_pos.Value = -Inf;
            app.RateControlPanel_XY = uipanel(app.XYTab);
            app.RateControlPanel_XY.Title = 'Rate Control:';
            app.GainPEditField_8Label = uilabel(app.RateControlPanel_XY);
            app.GainPEditField_8Label.Text = 'Gain P:';
            app.GainPEditField_XY_rate = uieditfield(app.RateControlPanel_XY, 'numeric');
            app.GainPEditField_XY_rate.Value = 1;
            app.GainIEditField_8Label = uilabel(app.RateControlPanel_XY);
            app.GainIEditField_8Label.Text = 'Gain I:';
            app.GainIEditField_XY_rate = uieditfield(app.RateControlPanel_XY, 'numeric');
            app.GainDEditField_8Label = uilabel(app.RateControlPanel_XY);
            app.GainDEditField_8Label.Text = 'Gain D:';
            app.GainDEditField_XY_rate = uieditfield(app.RateControlPanel_XY, 'numeric');
            app.SatEditField_15Label = uilabel(app.RateControlPanel_XY);
            app.SatEditField_15Label.Text = 'Sat +:';
            app.SatPosEditField_XY_rate = uieditfield(app.RateControlPanel_XY, 'numeric');
            app.SatPosEditField_XY_rate.Value = Inf;
            app.SatEditField_16Label = uilabel(app.RateControlPanel_XY);
            app.SatEditField_16Label.Text = 'Sat -:';
            app.SatNegEditField_XY_rate = uieditfield(app.RateControlPanel_XY, 'numeric');
            app.SatNegEditField_XY_rate.Value = -Inf;
            app.ZTab = uitab(app.TabGroup);
            app.ZTab.Title = 'Z';
            app.PositionControlPanel_Z = uipanel(app.ZTab);
            app.PositionControlPanel_Z.Title = 'Position Control:';
            app.GainPEditField_11Label = uilabel(app.PositionControlPanel_Z);
            app.GainPEditField_11Label.Text = 'Gain P:';
            app.GainPEditField_Z_pos = uieditfield(app.PositionControlPanel_Z, 'numeric');
            app.GainPEditField_Z_pos.Value = 1;
            app.GainIEditField_11Label = uilabel(app.PositionControlPanel_Z);
            app.GainIEditField_11Label.Text = 'Gain I:';
            app.GainIEditField_Z_pos = uieditfield(app.PositionControlPanel_Z, 'numeric');
            app.GainDEditField_11Label = uilabel(app.PositionControlPanel_Z);
            app.GainDEditField_11Label.Text = 'Gain D:';
            app.GainDEditField_Z_pos = uieditfield(app.PositionControlPanel_Z, 'numeric');
            app.SatEditField_21Label = uilabel(app.PositionControlPanel_Z);
            app.SatEditField_21Label.Text = 'Sat +:';
            app.SatPosEditField_Z_pos = uieditfield(app.PositionControlPanel_Z, 'numeric');
            app.SatPosEditField_Z_pos.Value = Inf;
            app.SatEditField_22Label = uilabel(app.PositionControlPanel_Z);
            app.SatEditField_22Label.Text = 'Sat -:';
            app.SatNegEditField_Z_pos = uieditfield(app.PositionControlPanel_Z, 'numeric');
            app.SatNegEditField_Z_pos.Value = -Inf;
            app.RateControlPanel_Z = uipanel(app.ZTab);
            app.RateControlPanel_Z.Title = 'Rate Control:';
            app.GainPEditField_12Label = uilabel(app.RateControlPanel_Z);
            app.GainPEditField_12Label.Text = 'Gain P:';
            app.GainPEditField_Z_rate = uieditfield(app.RateControlPanel_Z, 'numeric');
            app.GainPEditField_Z_rate.Value = 1;
            app.GainIEditField_12Label = uilabel(app.RateControlPanel_Z);
            app.GainIEditField_12Label.Text = 'Gain I:';
            app.GainIEditField_Z_rate = uieditfield(app.RateControlPanel_Z, 'numeric');
            app.GainDEditField_12Label = uilabel(app.RateControlPanel_Z);
            app.GainDEditField_12Label.Text = 'Gain D:';
            app.GainDEditField_Z_rate = uieditfield(app.RateControlPanel_Z, 'numeric');
            app.SatEditField_23Label = uilabel(app.RateControlPanel_Z);
            app.SatEditField_23Label.Text = 'Sat +:';
            app.SatPosEditField_Z_rate = uieditfield(app.RateControlPanel_Z, 'numeric');
            app.SatPosEditField_Z_rate.Value = Inf;
            app.SatEditField_24Label = uilabel(app.RateControlPanel_Z);
            app.SatEditField_24Label.Text = 'Sat -:';
            app.SatNegEditField_Z_rate = uieditfield(app.RateControlPanel_Z, 'numeric');
            app.SatNegEditField_Z_rate.Value = -Inf;
            app.GeometricControlTab = uitab(app.TabGroup2);
            app.GeometricControlTab.Title = 'Geometric Control';
            app.UAVparametersPanel = uipanel(app.GeometricControlTab);
            app.UAVparametersPanel.Title = 'UAV parameters:';
            app.MassKgEditFieldLabel = uilabel(app.UAVparametersPanel);
            app.MassKgEditFieldLabel.Text = 'Mass [Kg] :';
            app.MassKgEditField = uieditfield(app.UAVparametersPanel, 'numeric');
            app.C_TfmEditFieldLabel = uilabel(app.UAVparametersPanel);
            app.C_TfmEditFieldLabel.Text = 'C_Tf  [m] :';
            app.C_TfmEditField = uieditfield(app.UAVparametersPanel, 'numeric');
            app.dmEditFieldLabel = uilabel(app.UAVparametersPanel);
            app.dmEditFieldLabel.Text = 'd [m] :';
            app.dmEditField = uieditfield(app.UAVparametersPanel, 'numeric');
            app.Jkgm2EditFieldLabel = uilabel(app.UAVparametersPanel);
            app.Jkgm2EditFieldLabel.Text = 'J [kgm^2] :';
            app.Jkgm2EditField = uieditfield(app.UAVparametersPanel, 'text');
            app.AttitudegainsPanel = uipanel(app.UAVparametersPanel);
            app.AttitudegainsPanel.Title = 'Attitude gains:';
            app.k_OEditFieldLabel = uilabel(app.AttitudegainsPanel);
            app.k_OEditFieldLabel.Text = 'k_O:';
            app.k_OEditField = uieditfield(app.AttitudegainsPanel, 'text');
            app.k_REditField_3Label = uilabel(app.AttitudegainsPanel);
            app.k_REditField_3Label.Text = 'k_R:';
            app.k_REditField = uieditfield(app.AttitudegainsPanel, 'text');
            app.k_IEditFieldLabel = uilabel(app.AttitudegainsPanel);
            app.k_IEditFieldLabel.Text = 'k_I:';
            app.k_IEditField = uieditfield(app.AttitudegainsPanel, 'text');
            app.c2EditFieldLabel = uilabel(app.AttitudegainsPanel);
            app.c2EditFieldLabel.Text = 'c2:';
            app.c2EditField = uieditfield(app.AttitudegainsPanel, 'text');
            app.PositiongainsPanel = uipanel(app.UAVparametersPanel);
            app.PositiongainsPanel.Title = 'Position gains:';
            app.k_xEditFieldLabel = uilabel(app.PositiongainsPanel);
            app.k_xEditFieldLabel.Text = 'k_x:';
            app.k_xEditField = uieditfield(app.PositiongainsPanel, 'text');
            app.k_vEditFieldLabel = uilabel(app.PositiongainsPanel);
            app.k_vEditFieldLabel.Text = 'k_v:';
            app.k_vEditField = uieditfield(app.PositiongainsPanel, 'text');
            app.k_iEditFieldLabel = uilabel(app.PositiongainsPanel);
            app.k_iEditFieldLabel.Text = 'k_i:';
            app.k_iEditField = uieditfield(app.PositiongainsPanel, 'text');
            app.c1EditFieldLabel = uilabel(app.PositiongainsPanel);
            app.c1EditFieldLabel.Text = 'c1:';
            app.c1EditField = uieditfield(app.PositiongainsPanel, 'text');
            app.UpdateparametersButton = uibutton(app.UIControllersUIFigure, 'push');
            app.UpdateparametersButton.ButtonPushedFcn = createCallbackFcn(app, @update_control_gains_fcn, true);
            app.UpdateparametersButton.Text = 'Update parameters';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appControllers

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIControllersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIControllersUIFigure)
        end
    end
end