classdef appFilters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFiltersUIFigure             matlab.ui.Figure
        AttitudePanel                 matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        MPU9250Tab                    matlab.ui.container.Tab
        kbEditField_3Label            matlab.ui.control.Label
        kbEditField_mpu               matlab.ui.control.NumericEditField
        k1EditField_3Label            matlab.ui.control.Label
        k1EditField_mpu               matlab.ui.control.NumericEditField
        k2EditField_3Label            matlab.ui.control.Label
        k2EditField_mpu               matlab.ui.control.NumericEditField
        k3EditField_3Label            matlab.ui.control.Label
        k3EditField_mpu               matlab.ui.control.NumericEditField
        k4EditField_3Label            matlab.ui.control.Label
        k4EditField_mpu               matlab.ui.control.NumericEditField
        limitEditField_3Label         matlab.ui.control.Label
        limitEditField_mpu            matlab.ui.control.NumericEditField
        offsetEditFieldLabel          matlab.ui.control.Label
        offsetEditField_mpu           matlab.ui.control.EditField
        LSM9DS1Tab                    matlab.ui.container.Tab
        kbEditField_2Label            matlab.ui.control.Label
        kbEditField_lsm               matlab.ui.control.NumericEditField
        k1EditField_2Label            matlab.ui.control.Label
        k1EditField_lsm               matlab.ui.control.NumericEditField
        k2EditField_2Label            matlab.ui.control.Label
        k2EditField_lsm               matlab.ui.control.NumericEditField
        k4EditField_2Label            matlab.ui.control.Label
        k4EditField_lsm               matlab.ui.control.NumericEditField
        limitEditField_2Label         matlab.ui.control.Label
        limitEditField_lsm            matlab.ui.control.NumericEditField
        k3EditField_2Label            matlab.ui.control.Label
        k3EditField_lsm               matlab.ui.control.NumericEditField
        offsetEditField_2Label        matlab.ui.control.Label
        offsetEditField_lsm           matlab.ui.control.EditField
        PositionPanel                 matlab.ui.container.Panel
        TabGroup2                     matlab.ui.container.TabGroup
        EKFTab                        matlab.ui.container.Tab
        Positionsf11mEditFieldLabel   matlab.ui.control.Label
        Positionsf11mEditField        matlab.ui.control.NumericEditField
        PositionprismmEditFieldLabel  matlab.ui.control.Label
        PositionprismEditField        matlab.ui.control.NumericEditField
        KQEditFieldLabel              matlab.ui.control.Label
        KQEditField                   matlab.ui.control.EditField
        KREditFieldLabel              matlab.ui.control.Label
        KREditField                   matlab.ui.control.EditField
        UpdateparametersButton        matlab.ui.control.Button
        CalibrationButton             matlab.ui.control.Button
    end


    properties (Access = private)
           
           nameModelSimulink
           pathProject
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %MPU imu estimator attitude
            [pathWorkspace,app.nameModelSimulink,nameProjectFolder,~,~,~] = generatePathandNames();
            app.pathProject = [pathWorkspace,nameProjectFolder];
            app.kbEditField_mpu.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/kb'],'Value'));
            app.k1EditField_mpu.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/k1'],'Value'));
            app.k2EditField_mpu.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/k2'],'Value'));
            app.k3EditField_mpu.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/k3'],'Value'));
            app.k4EditField_mpu.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/k4'],'Value'));
            app.limitEditField_mpu.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/limit'],'Value'));
            app.offsetEditField_mpu.Value = get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/offset'],'Value');
            %LSM imu estimator attitude
            app.kbEditField_lsm.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/kb'],'Value'));
            app.k1EditField_lsm.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/k1'],'Value'));
            app.k2EditField_lsm.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/k2'],'Value'));
            app.k3EditField_lsm.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/k3'],'Value'));
            app.k4EditField_lsm.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/k4'],'Value'));
            app.limitEditField_lsm.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/limit'],'Value'));
            app.offsetEditField_lsm.Value = get_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/offset'],'Value');
            %EKF xyz Total station and SF11/c
            app.KQEditField.Value = get_param([app.nameModelSimulink,'/Sensors/position/KQ'],'Value');
            app.KREditField.Value = get_param([app.nameModelSimulink,'/Sensors/position/KR'],'Value');
            app.Positionsf11mEditField.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/position/d_sf11'],'Value'));
            app.PositionprismEditField.Value = str2double(get_param([app.nameModelSimulink,'/Sensors/position/d_prism'],'Value'));
        end

        % Button pushed function: UpdateparametersButton
        function update_paremters_fcn(app, event)
            %MPU imu estimator attitude
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/kb'],'Value',num2str(app.kbEditField_mpu.Value));
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/k1'],'Value',num2str(app.k1EditField_mpu.Value));
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/k2'],'Value',num2str(app.k2EditField_mpu.Value));
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/k3'],'Value',num2str(app.k3EditField_mpu.Value));
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/k4'],'Value',num2str(app.k4EditField_mpu.Value));
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/limit'],'Value',num2str(app.limitEditField_mpu.Value));
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/mpu9250/offset'],'Value',app.offsetEditField_mpu.Value);
            %LSM imu estimator attitude
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/kb'],'Value',num2str(app.kbEditField_lsm.Value));
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/k1'],'Value',num2str(app.k1EditField_lsm.Value));
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/k2'],'Value',num2str(app.k2EditField_lsm.Value));
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/k3'],'Value',num2str(app.k3EditField_lsm.Value));
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/k4'],'Value',num2str(app.k4EditField_lsm.Value));
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/limit'],'Value',num2str(app.limitEditField_lsm.Value));
             set_param([app.nameModelSimulink,'/Sensors/attitude/AttitudeEstimator/lsm9ds1/offset'],'Value',app.offsetEditField_lsm.Value);
            %EKF xyz Total station and SF11/c
             set_param([app.nameModelSimulink,'/Sensors/position/KQ'],'Value',app.KQEditField.Value);
             set_param([app.nameModelSimulink,'/Sensors/position/KR'],'Value',app.KREditField.Value);
             set_param([app.nameModelSimulink,'/Sensors/position/d_sf11'],'Value',num2str(app.Positionsf11mEditField.Value));
             set_param([app.nameModelSimulink,'/Sensors/position/d_prism'],'Value',num2str(app.PositionprismEditField.Value));
        end

        % Close request function: UIFiltersUIFigure
        function UIFiltersUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: CalibrationButton
        function update_Calibration_parameters_fcn(app, event)
            get_calibration_params(app.pathProject,app.nameModelSimulink)
            %run(app.getCalibrationParamsPath);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFiltersUIFigure = uifigure;
            app.UIFiltersUIFigure.Name = 'UIFilters';
            app.UIFiltersUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFiltersUIFigureCloseRequest, true);
            app.AttitudePanel = uipanel(app.UIFiltersUIFigure);
            app.AttitudePanel.Title = 'Attitude:';
            app.TabGroup = uitabgroup(app.AttitudePanel);
            app.MPU9250Tab = uitab(app.TabGroup);
            app.MPU9250Tab.Title = 'MPU9250';
            app.kbEditField_3Label = uilabel(app.MPU9250Tab);
            app.kbEditField_3Label.Text = 'kb :';
            app.kbEditField_mpu = uieditfield(app.MPU9250Tab, 'numeric');
            app.k1EditField_3Label = uilabel(app.MPU9250Tab);
            app.k1EditField_3Label.Text = 'k1 :';
            app.k1EditField_mpu = uieditfield(app.MPU9250Tab, 'numeric');
            app.k2EditField_3Label = uilabel(app.MPU9250Tab);
            app.k2EditField_3Label.Text = 'k2 :';
            app.k2EditField_mpu = uieditfield(app.MPU9250Tab, 'numeric');
            app.k3EditField_3Label = uilabel(app.MPU9250Tab);
            app.k3EditField_3Label.Text = 'k3 :';
            app.k3EditField_mpu = uieditfield(app.MPU9250Tab, 'numeric');
            app.k4EditField_3Label = uilabel(app.MPU9250Tab);
            app.k4EditField_3Label.Text = 'k4 :';
            app.k4EditField_mpu = uieditfield(app.MPU9250Tab, 'numeric');
            app.limitEditField_3Label = uilabel(app.MPU9250Tab);
            app.limitEditField_3Label.Text = 'limit :';
            app.limitEditField_mpu = uieditfield(app.MPU9250Tab, 'numeric');
            app.offsetEditFieldLabel = uilabel(app.MPU9250Tab);
            app.offsetEditFieldLabel.Text = 'offset';
            app.offsetEditField_mpu = uieditfield(app.MPU9250Tab, 'text');
            app.LSM9DS1Tab = uitab(app.TabGroup);
            app.LSM9DS1Tab.Title = 'LSM9DS1';
            app.kbEditField_2Label = uilabel(app.LSM9DS1Tab);
            app.kbEditField_2Label.Text = 'kb :';
            app.kbEditField_lsm = uieditfield(app.LSM9DS1Tab, 'numeric');
            app.k1EditField_2Label = uilabel(app.LSM9DS1Tab);
            app.k1EditField_2Label.Text = 'k1 :';
            app.k1EditField_lsm = uieditfield(app.LSM9DS1Tab, 'numeric');
            app.k2EditField_2Label = uilabel(app.LSM9DS1Tab);
            app.k2EditField_2Label.Text = 'k2 :';
            app.k2EditField_lsm = uieditfield(app.LSM9DS1Tab, 'numeric');
            app.k4EditField_2Label = uilabel(app.LSM9DS1Tab);
            app.k4EditField_2Label.Text = 'k4 :';
            app.k4EditField_lsm = uieditfield(app.LSM9DS1Tab, 'numeric');
            app.limitEditField_2Label = uilabel(app.LSM9DS1Tab);
            app.limitEditField_2Label.Text = 'limit :';
            app.limitEditField_lsm = uieditfield(app.LSM9DS1Tab, 'numeric');
            app.k3EditField_2Label = uilabel(app.LSM9DS1Tab);
            app.k3EditField_2Label.Text = 'k3 :';
            app.k3EditField_lsm = uieditfield(app.LSM9DS1Tab, 'numeric');
            app.offsetEditField_2Label = uilabel(app.LSM9DS1Tab);
            app.offsetEditField_2Label.Text = 'offset';
            app.offsetEditField_lsm = uieditfield(app.LSM9DS1Tab, 'text');
            app.PositionPanel = uipanel(app.UIFiltersUIFigure);
            app.PositionPanel.Title = 'Position:';
            app.TabGroup2 = uitabgroup(app.PositionPanel);
            app.EKFTab = uitab(app.TabGroup2);
            app.EKFTab.Title = 'EKF';
            app.Positionsf11mEditFieldLabel = uilabel(app.EKFTab);
            app.Positionsf11mEditFieldLabel.Text = 'Position sf11 [m] :  ';
            app.Positionsf11mEditField = uieditfield(app.EKFTab, 'numeric');
            app.PositionprismmEditFieldLabel = uilabel(app.EKFTab);
            app.PositionprismmEditFieldLabel.Text = 'Position prism [m] :';
            app.PositionprismEditField = uieditfield(app.EKFTab, 'numeric');
            app.KQEditFieldLabel = uilabel(app.EKFTab);
            app.KQEditFieldLabel.Text = 'KQ : ';
            app.KQEditField = uieditfield(app.EKFTab, 'text');
            app.KREditFieldLabel = uilabel(app.EKFTab);
            app.KREditFieldLabel.Text = 'KR : ';
            app.KREditField = uieditfield(app.EKFTab, 'text');
            app.UpdateparametersButton = uibutton(app.UIFiltersUIFigure, 'push');
            app.UpdateparametersButton.ButtonPushedFcn = createCallbackFcn(app, @update_paremters_fcn, true);
            app.UpdateparametersButton.Text = 'Update parameters';
            app.CalibrationButton = uibutton(app.UIFiltersUIFigure, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @update_Calibration_parameters_fcn, true);
            app.CalibrationButton.Text = 'Calibration';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appFilters

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFiltersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFiltersUIFigure)
        end
    end
end